classdef cellComparator < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure            matlab.ui.Figure
        accept              matlab.ui.control.Button
        reject              matlab.ui.control.Button
        previous            matlab.ui.control.Button
        next                matlab.ui.control.Button
        Lamp                matlab.ui.control.Lamp
        exportButton        matlab.ui.control.Button
        heading_cutout_ax   matlab.ui.control.UIAxes
        heading_trace_ax    matlab.ui.control.UIAxes
        platform_trace_ax   matlab.ui.control.UIAxes
        platform_cutout_ax  matlab.ui.control.UIAxes
        tuning_curve_ax     matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        heading_data % heading analyzer data
        platform_data  % platform analyzer object
        heading_tc
        platform_tc
        cellmasks % cell masks
        idx  % current cell idx
        head_avg_projection
        plat_avg_projection
        is_matched
        
        heading_cutout_plot
        heading_cell_plot
        heading_trace_plot
        platform_trace_plot
        platform_cutout_plot
        platform_cell_plot
        heading_tc_plot
        platform_tc_plot
        
        heading_color = [137, 207, 241]./255;
        platform_color = [255, 179, 71]./255;
        
        plot_idx = 1:10:2550; %size(app.heading_data.DFF, 2);
        
        viewer_mode
    end
    
    
    methods (Access = private)
        function displayCellCutout(app)
            current_cell = app.cellmasks{app.idx};
            bounds = [min(current_cell) - 10; max(current_cell) + 10]';
          
            
            % Check to make sure they increase, I guess...
            bounds(bounds(:, 2) <= bounds(:, 1), 1) = bounds(bounds(:, 2) <= bounds(:,1), 1) - 1;
            bounds = max(bounds, 1);
            bounds(1, :) = min(bounds(1, :), size(app.head_avg_projection, 2));
            bounds(2, :) = min(bounds(2, :), size(app.head_avg_projection, 1));
           
            cutout = app.head_avg_projection(bounds(2, 1) : bounds(2, 2), bounds(1, 1) : bounds(1, 2));
            max_val = max(cutout(:));
            min_val = min(cutout(:));
            if min_val == max_val
                max_val = max_val + 1;
            end
            
            app.heading_cutout_plot.CData = app.head_avg_projection;
            app.heading_cutout_ax.CLim = [min_val, max_val];
            app.heading_cell_plot.XData = current_cell(:, 1);
            app.heading_cell_plot.YData = current_cell(:, 2);
            xlim(app.heading_cutout_ax, bounds(1, :))
            ylim(app.heading_cutout_ax, bounds(2, :))
            colormap(app.heading_cutout_ax, 'gray')
            
            cutout = app.plat_avg_projection(bounds(2, 1) : bounds(2, 2), bounds(1, 1) : bounds(1, 2));
            max_val = max(cutout(:));
            min_val = min(cutout(:));
      if min_val == max_val
                max_val = max_val + 1;
      end            
            app.platform_cutout_plot.CData = app.plat_avg_projection;
            app.platform_cutout_ax.CLim = [min_val, max_val];
            app.platform_cell_plot.XData = current_cell(:, 1);
            app.platform_cell_plot.YData = current_cell(:, 2);
            xlim(app.platform_cutout_ax, bounds(1, :))
            ylim(app.platform_cutout_ax, bounds(2, :))
            hold(app.platform_cutout_ax, 'off')
            colormap(app.platform_cutout_ax, 'gray')
        end
        
        function plotDFF(app)
            app.heading_trace_plot.YData = app.heading_data(app.idx, :);
            app.platform_trace_plot.YData = app.platform_data(app.idx, :);
        end
        
        function plotTuningCurves(app)
            app.heading_tc_plot.YData = app.heading_tc(app.idx, :);
            app.platform_tc_plot.YData = circshift(app.platform_tc(app.idx, :), randi(size(app.platform_tc, 2)));
        end
        
        
        function update(app)
            disp(app.idx)
            if app.idx == numel(app.cellmasks)
                app.exportResults();
                app.delete();
                return
            end
            app.displayCellCutout();
            app.plotDFF();
            app.plotTuningCurves();
            app.unlockButtons();
            drawnow;
        end
        
        function enable(app, button)
            button.Enable = 'on';
        end
        
        function disable(app, button)
            button.Enable = 'off';
        end
        
        function lockButtons(app)
            app.disable(app.accept);
            app.disable(app.reject);
            app.disable(app.next);
            app.disable(app.previous);
        end
        
        function unlockButtons(app)
            app.enable(app.accept);
            app.enable(app.reject);
            app.enable(app.next);
            app.enable(app.previous);
        end
        
        function initialize(app)
            app.heading_cutout_plot = imagesc(app.heading_cutout_ax);
            hold(app.heading_cutout_ax, 'on');
            app.heading_cell_plot = plot(app.heading_cutout_ax, 1, 'Color', app.heading_color, 'LineStyle', ':');
            hold(app.heading_cutout_ax, 'off');
            
            app.heading_trace_plot = plot(app.heading_trace_ax, app.plot_idx, zeros(1, length(app.plot_idx)), 'Color', app.heading_color);
%             xlim(app.heading_trace_ax, [1, length(app.plot_idx)]);
            
            app.platform_cutout_plot = imagesc(app.platform_cutout_ax);
            hold(app.platform_cutout_ax, 'on');
            app.platform_cell_plot = plot(app.platform_cutout_ax, 1, 'Color', app.platform_color, 'LineStyle', ':');
            hold(app.platform_cutout_ax, 'off');
            app.platform_trace_plot = plot(app.platform_trace_ax, app.plot_idx, zeros(1, length(app.plot_idx)), 'Color', app.platform_color);
%                         xlim(app.platform_trace_plot, [1, length(app.plot_idx)]);

            app.heading_tc_plot = plot(app.tuning_curve_ax, 1:60, zeros(1, 60), 'Color', app.heading_color);
            hold(app.tuning_curve_ax, 'on');
            app.platform_tc_plot = plot(app.tuning_curve_ax, 1:60, zeros(1, 60), 'Color', app.platform_color);
            hold(app.tuning_curve_ax, 'off');
%             xlim(app.tuning_curve_ax, [1, 60]);
        end
        
        function exportResults(app)
            fprintf('Variables created in the base workspace\n');
            assignin('base', 'is_matched', app.is_matched);
        end
        
        function checks(app)
            % mainly check to see if the data are correct..
            if size(app.heading_data, 1) ~= size(app.platform_data, 1)
                error('Something''s wrong, the recordings do not have the same number of cells.');i
            end
        end
        
        function incrementIdx(app)
            if app.viewer_mode
                matched_idx = find(app.is_matched);
                current_idx = find(matched_idx == app.idx);
                app.idx = matched_idx(current_idx + 1);
            else
                app.idx = app.idx + 1;
            end
            
        end
        
        function decrementIdx(app)
            if app.viewer_mode
                matched_idx = find(app.is_matched);
                current_idx = find(matched_idx == app.idx);
                app.idx = matched_idx(current_idx - 1);
            else
                app.idx = app.idx - 1;     
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, input_struct, viewer_mode)
            if ~isfield(input_struct, 'is_matched')
                input_struct.is_matched = false(1, numel(app.cellmasks));
            end
            
            if nargin < 3 || isempty(viewer_mode)
                viewer_mode = false;
            end
            
            app.viewer_mode = viewer_mode;
            
            % extract relevant data
            heading_data = input_struct.rec1.data.get('DFF');
            app.heading_data = heading_data(:, app.plot_idx);
            
            platform_data = input_struct.rec2.data.get('DFF');
            app.platform_data = platform_data(:, app.plot_idx);
            
            app.head_avg_projection = input_struct.rec1_avg_projection;
            app.plat_avg_projection = input_struct.rec2_avg_projection;
            
            app.heading_tc = rowrescale(input_struct.rec1.tuning_curves);
            app.platform_tc = rowrescale(input_struct.rec2.tuning_curves);
            
            app.cellmasks = input_struct.rec1_cellmasks;
            
            app.is_matched = input_struct.is_matched;
            if app.viewer_mode
                app.idx = find(app.is_matched, 1, 'first');
            else
                app.idx = 1;
            end
            
            app.checks();
            
            app.initialize();
            app.update();
        end

        % Button pushed function: accept
        function acceptButtonPushed(app, event)
            app.lockButtons();
            app.is_matched(app.idx) = true;
            app.idx = app.idx + 1;
            app.update();
        end

        % Button pushed function: reject
        function rejectButtonPushed(app, event)
            app.lockButtons();
            app.is_matched(app.idx) = false;
            app.incrementIdx();
            app.update();
        end

        % Button pushed function: next
        function nextButtonPushed(app, event)
            app.lockButtons();
            app.incrementIdx();
            app.update();
        end

        % Button pushed function: previous
        function previousButtonPushed(app, event)
            app.lockButtons();
            app.decrementIdx();
            app.update();
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case 'rightarrow'
                    app.nextButtonPushed();
                case 'leftarrow'
                    app.previousButtonPushed();
                case 'a'
                    app.acceptButtonPushed();
                case 'semicolon'
                    app.rejectButtonPushed();
            end
        end

        % Button pushed function: exportButton
        function exportButtonPushed(app, event)
               app.exportResults();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.accept = uibutton(app.UIFigure, 'push');
            app.accept.ButtonPushedFcn = createCallbackFcn(app, @acceptButtonPushed, true);
            app.accept.Text = 'ACCEPT';
            app.reject = uibutton(app.UIFigure, 'push');
            app.reject.ButtonPushedFcn = createCallbackFcn(app, @rejectButtonPushed, true);
            app.reject.Text = 'REJECT';
            app.previous = uibutton(app.UIFigure, 'push');
            app.previous.ButtonPushedFcn = createCallbackFcn(app, @previousButtonPushed, true);
            app.previous.Text = 'prev';
            app.next = uibutton(app.UIFigure, 'push');
            app.next.ButtonPushedFcn = createCallbackFcn(app, @nextButtonPushed, true);
            app.next.Text = 'next';
            app.Lamp = uilamp(app.UIFigure);
            app.exportButton = uibutton(app.UIFigure, 'push');
            app.exportButton.ButtonPushedFcn = createCallbackFcn(app, @exportButtonPushed, true);
            app.exportButton.Text = 'export';
            app.heading_cutout_ax = uiaxes(app.UIFigure);
            app.heading_cutout_ax.XTick = [];
            app.heading_cutout_ax.YTick = [];
            app.heading_trace_ax = uiaxes(app.UIFigure);
            zlabel(app.heading_trace_ax, 'Z')
            app.platform_trace_ax = uiaxes(app.UIFigure);
            xlabel(app.platform_trace_ax, 'frame')
            ylabel(app.platform_trace_ax, 'DFF')
            zlabel(app.platform_trace_ax, 'Z')
            app.platform_cutout_ax = uiaxes(app.UIFigure);
            app.platform_cutout_ax.XTick = [];
            app.platform_cutout_ax.XTickLabel = '';
            app.platform_cutout_ax.YTick = [];
            app.platform_cutout_ax.YTickLabel = '';
            app.tuning_curve_ax = uiaxes(app.UIFigure);
            xlabel(app.tuning_curve_ax, 'heading')
            zlabel(app.tuning_curve_ax, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cellComparator(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end